classdef Run_Here < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RUNFULLANALYSISButton          matlab.ui.control.Button
        CALCULATEDPARAMETERSPanel      matlab.ui.container.Panel
        CEditField_2                   matlab.ui.control.EditField
        AEditField_2                   matlab.ui.control.EditField
        DEditField_2                   matlab.ui.control.EditField
        BEditField_2                   matlab.ui.control.EditField
        DEditField                     matlab.ui.control.EditField
        DEditFieldLabel                matlab.ui.control.Label
        CEditField                     matlab.ui.control.EditField
        CEditFieldLabel                matlab.ui.control.Label
        BEditField                     matlab.ui.control.EditField
        BEditFieldLabel                matlab.ui.control.Label
        AEditField                     matlab.ui.control.EditField
        AEditFieldLabel                matlab.ui.control.Label
        ResultsTable                   matlab.ui.control.Table
        PERFORMANCECURVESPanel         matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        SYSTEMINPUTSPanel              matlab.ui.container.Panel
        PerformanceCaseDropDown        matlab.ui.control.DropDown
        PerformanceCaseDropDownLabel   matlab.ui.control.Label
        ReceivingEndVoltageVEditField  matlab.ui.control.NumericEditField
        ReceivingEndVoltageVEditFieldLabel  matlab.ui.control.Label
        MediumLineModelDropDown        matlab.ui.control.DropDown
        MediumLineModelDropDownLabel   matlab.ui.control.Label
        DistanceD_caEditField          matlab.ui.control.NumericEditField
        DistanceD_caEditFieldLabel     matlab.ui.control.Label
        DistanceD_bcEditField          matlab.ui.control.NumericEditField
        DistanceD_bcEditFieldLabel     matlab.ui.control.Label
        DistanceD_abEditField          matlab.ui.control.NumericEditField
        DistanceD_abEditFieldLabel     matlab.ui.control.Label
        DistanceDmEditField            matlab.ui.control.NumericEditField
        DistanceDLabel                 matlab.ui.control.Label
        SpacingTypeButtonGroup         matlab.ui.container.ButtonGroup
        UnsymmetricalSpacingButton     matlab.ui.control.RadioButton
        SymmetricalSpacingButton       matlab.ui.control.RadioButton
        ConductorDiameterdcmEditField  matlab.ui.control.NumericEditField
        ConductorDiameterdcmEditFieldLabel  matlab.ui.control.Label
        ConductorLengthLmEditField     matlab.ui.control.NumericEditField
        ConductorLengthLmEditFieldLabel  matlab.ui.control.Label
        ConductorResistivityrhoOmegaEditField  matlab.ui.control.NumericEditField
        ConductorResistivityrhoohmLabel  matlab.ui.control.Label
        TRANSMISSIONLINEANALYZERLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function func(app)
            app.UIAxes3.Visible = 'off';
            app.UIAxes4.Visible = 'off';
            app.GridLayout.ColumnWidth = {'17.24x',0};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConductorResistivityrhoohmLabel.Interpreter = 'tex';
            app.ConductorDiameterdcmEditFieldLabel.Interpreter = 'tex';
            app.ConductorLengthLmEditFieldLabel.Interpreter = 'tex';
            app.DistanceD_abEditFieldLabel.Interpreter = 'tex';
            app.DistanceD_bcEditFieldLabel.Interpreter = 'tex';
            app.DistanceD_caEditFieldLabel.Interpreter = 'tex';
            app.DistanceDLabel.Interpreter = 'tex';
            % Turn ON the single Distance box
            app.DistanceDmEditField.Enable = 'on';
            
            % Turn OFF the three Unsymmetrical boxes
            app.DistanceD_abEditField.Enable = 'off';
            app.DistanceD_bcEditField.Enable = 'off';
            app.DistanceD_caEditField.Enable = 'off';
        end

        % Button pushed function: RUNFULLANALYSISButton
        function RUNFULLANALYSISButtonPushed(app, event)
            % --- 1. EXTRACT: Get User Inputs from GUI ---
rho = app.ConductorResistivityrhoOmegaEditField.Value;
l = app.ConductorLengthLmEditField.Value;
d = app.ConductorDiameterdcmEditField.Value;
V_R_line = app.ReceivingEndVoltageVEditField.Value;

% Figure out which spacing the user picked
if app.SpacingTypeButtonGroup.SelectedObject == app.SymmetricalSpacingButton
    TS = 1;
    D_vals = [app.DistanceDmEditField.Value]; % Only one distance
else
    TS = 2;
    D_vals = [app.DistanceD_abEditField.Value, app.DistanceD_bcEditField.Value, app.DistanceD_caEditField.Value];
end

%% ================= CALL TASK 1 =================
% The app hands the inputs to transmission_calc.m and gets R, L, C back
[R, L_ph, C_ph] = Task_1(rho, l, d, TS, D_vals);

% LOAD: Push results to the Table
app.ResultsTable.Data = {
    'Resistance', 'R', num2str(R, '%.4f'), 'Ohms';
    'Inductance', 'L', num2str(L_ph, '%.4e'), 'H/m';
    'Capacitance', 'C', num2str(C_ph, '%.4e'), 'F/m'
};

%% ================= CALL TASK 2  =================
f = 60; 

% Match the dropdown text to what Colleague 2's code expects ('pi' or 'T')
modelChoice = app.MediumLineModelDropDown.Value;
if strcmp(modelChoice, 'Nominal Pi')
    modelStr = 'pi';
else
    modelStr = 'T';
end

% Call ABCD_calc.m
[A, B, C, D] = Task2(R, L_ph, C_ph, l, modelStr, f);

% LOAD: Push complex ABCD values to the App Text Edit Fields
app.AEditField.Value = sprintf('%.4f + %.4fi', real(A), imag(A));
app.AEditField_2.Value = sprintf('%.4f%c%.4f', abs(A),char(8736), angle(A));
app.BEditField.Value = sprintf('%.4f + %.4fi', real(B), imag(B));
app.BEditField_2.Value = sprintf('%.4f%c%.4f', abs(B),char(8736), angle(B));
app.CEditField.Value = sprintf('%.4f + %.4fi', real(C), imag(C));
app.CEditField_2.Value = sprintf('%.4f%c%.4f', abs(C),char(8736), angle(C));
app.DEditField.Value = sprintf('%.4f + %.4fi', real(D), imag(D));
app.DEditField_2.Value = sprintf('%.4f%c%.4f', abs(D),char(8736), angle(D));

%% ================= CALL TASK 3 =================
caseChoice = app.PerformanceCaseDropDown.Value;

if strcmp(caseChoice, 'CASE I')
    % Call your custom function and get the arrays back
    [p_kw, efficiency, V_reg] = task3_case1(A, B, C, D, V_R_line);
    app.UIAxes.Visible = 'on';
    app.UIAxes2.Visible = 'on';
    app.UIAxes3.Visible = 'off';
    app.UIAxes4.Visible = 'off';
    app.GridLayout.ColumnWidth = {'17.24x',0};

    % Draw the Efficiency graph INSIDE the App
    plot(app.UIAxes, p_kw, efficiency, 'b', 'LineWidth', 2);
    grid(app.UIAxes, 'on');
    title(app.UIAxes, 'Efficiency vs Load Active Power at 0.8 pf lag'); % Optional if title is already set in GUI
    xlabel(app.UIAxes,'Load Active Power P_L (kW)')
    ylabel(app.UIAxes,'Efficiency (\eta) (%)')
    %ylim(app.UIAxes,[0 100])
    xlim(app.UIAxes,[0 100])

    % Draw the Voltage Regulation graph INSIDE the App
    plot(app.UIAxes2, p_kw, V_reg, 'r', 'LineWidth', 2);
    grid(app.UIAxes2, 'on');
    title(app.UIAxes2, 'Voltage Regulation vs Load Active Power at 0.8 pf lag'); % Optional if title is already set in GUI
    xlabel(app.UIAxes2,'Load Active Power P_L (kW)')
    ylabel(app.UIAxes2,'Voltage Regulation V_R (%)')
    xlim(app.UIAxes2,[0 100])

else
    [pf_lag, efficiency_lag, V_reg_lag] = task3_case2_lag(A, B, C, D, V_R_line);
    [pf_lead, efficiency_lead, V_reg_lead] = task3_case2_lead(A, B, C, D, V_R_line);
    app.UIAxes.Visible = 'on';
    app.UIAxes2.Visible = 'on';
    app.UIAxes3.Visible = 'on';
    app.UIAxes4.Visible = 'on';
    app.GridLayout.ColumnWidth = {'9x','9x'};

    % Draw the Efficiency graph INSIDE the App
    plot(app.UIAxes, pf_lag, efficiency_lag, 'b', 'LineWidth', 2);
    grid(app.UIAxes, 'on');
    title(app.UIAxes, '\eta vs Lagging P_f'); % Optional if title is already set in GUI
    xlabel(app.UIAxes,'Power Factor P_f')
    ylabel(app.UIAxes,'Efficiency (\eta) (%)')
    xlim(app.UIAxes,[0.3 1])
    %ylim(app.UIAxes,[0 100])

    % Draw the Voltage Regulation graph INSIDE the App
    plot(app.UIAxes2, pf_lag, V_reg_lag, 'r', 'LineWidth', 2);
    grid(app.UIAxes2, 'on');
    title(app.UIAxes2, 'V_R vs Lagging P_f'); % Optional if title is already set in GUI
    xlabel(app.UIAxes2,'Power Factor P_f')
    ylabel(app.UIAxes2,'Voltage Regulation V_R (%)')
    xlim(app.UIAxes2,[0.3 1])

     % Draw the Efficiency graph INSIDE the App
    plot(app.UIAxes3, pf_lead, efficiency_lead, 'b', 'LineWidth', 2);
    grid(app.UIAxes3, 'on');
    title(app.UIAxes3, '\eta vs Leading P_f'); % Optional if title is already set in GUI
    xlabel(app.UIAxes3,'Power Factor P_f')
    ylabel(app.UIAxes3,'Efficiency (\eta) (%)')
    xlim(app.UIAxes3,[0.3 1])
    %ylim(app.UIAxes3,[0 100])

    % Draw the Voltage Regulation graph INSIDE the App
    plot(app.UIAxes4, pf_lead, V_reg_lead, 'r', 'LineWidth', 2);
    grid(app.UIAxes4, 'on');
    title(app.UIAxes4, 'V_R vs Leading P_f'); % Optional if title is already set in GUI
    xlabel(app.UIAxes4,'Power Factor P_f')
    ylabel(app.UIAxes4,'Voltage Regulation V_R (%)')
    xlim(app.UIAxes4,[0.3 1])
end
        end

        % Selection changed function: SpacingTypeButtonGroup
        function SpacingTypeButtonGroupSelectionChanged(app, event)
            
            
            % Find out which radio button is currently selected
selectedButton = app.SpacingTypeButtonGroup.SelectedObject;

if selectedButton == app.SymmetricalSpacingButton
    % Turn ON the single Distance box
    app.DistanceDmEditField.Enable = 'on';
    
    % Turn OFF the three Unsymmetrical boxes
    app.DistanceD_abEditField.Enable = 'off';
    app.DistanceD_bcEditField.Enable = 'off';
    app.DistanceD_caEditField.Enable = 'off';
    
elseif selectedButton == app.UnsymmetricalSpacingButton
    % Turn OFF the single Distance box
    app.DistanceDmEditField.Enable = 'off';
    
    % Turn ON the three Unsymmetrical boxes
    app.DistanceD_abEditField.Enable = 'on';
    app.DistanceD_bcEditField.Enable = 'on';
    app.DistanceD_caEditField.Enable = 'on';
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TRANSMISSIONLINEANALYZERLabel = uilabel(app.UIFigure);
            app.TRANSMISSIONLINEANALYZERLabel.Text = 'TRANSMISSION LINE ANALYZER';
            app.SYSTEMINPUTSPanel = uipanel(app.UIFigure);
            app.SYSTEMINPUTSPanel.Title = 'SYSTEM INPUTS';
            app.ConductorResistivityrhoohmLabel = uilabel(app.SYSTEMINPUTSPanel);
            app.ConductorResistivityrhoohmLabel.Text = 'Conductor Resistivity (\rho) (\Omega)';
            app.ConductorResistivityrhoOmegaEditField = uieditfield(app.SYSTEMINPUTSPanel, 'numeric');
            app.ConductorResistivityrhoOmegaEditField.Value = 1.72e-08;
            app.ConductorLengthLmEditFieldLabel = uilabel(app.SYSTEMINPUTSPanel);
            app.ConductorLengthLmEditFieldLabel.Text = 'Conductor Length (L) (m)';
            app.ConductorLengthLmEditField = uieditfield(app.SYSTEMINPUTSPanel, 'numeric');
            app.ConductorLengthLmEditField.Value = 50000;
            app.ConductorDiameterdcmEditFieldLabel = uilabel(app.SYSTEMINPUTSPanel);
            app.ConductorDiameterdcmEditFieldLabel.Text = 'Conductor Diameter (d) (cm)';
            app.ConductorDiameterdcmEditField = uieditfield(app.SYSTEMINPUTSPanel, 'numeric');
            app.ConductorDiameterdcmEditField.Value = 1.5;
            app.SpacingTypeButtonGroup = uibuttongroup(app.SYSTEMINPUTSPanel);
            app.SpacingTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpacingTypeButtonGroupSelectionChanged, true);
            app.SpacingTypeButtonGroup.Title = 'Spacing Type';
            app.SymmetricalSpacingButton = uiradiobutton(app.SpacingTypeButtonGroup);
            app.SymmetricalSpacingButton.Text = 'Symmetrical Spacing';
            app.SymmetricalSpacingButton.Value = true;
            app.UnsymmetricalSpacingButton = uiradiobutton(app.SpacingTypeButtonGroup);
            app.UnsymmetricalSpacingButton.Text = 'Unsymmetrical Spacing';
            app.DistanceDLabel = uilabel(app.SYSTEMINPUTSPanel);
            app.DistanceDLabel.Text = 'Distance (D) (m)';
            app.DistanceDmEditField = uieditfield(app.SYSTEMINPUTSPanel, 'numeric');
            app.DistanceDmEditField.Value = 5;
            app.DistanceD_abEditFieldLabel = uilabel(app.SYSTEMINPUTSPanel);
            app.DistanceD_abEditFieldLabel.Text = 'Distance D_ab';
            app.DistanceD_abEditField = uieditfield(app.SYSTEMINPUTSPanel, 'numeric');
            app.DistanceD_bcEditFieldLabel = uilabel(app.SYSTEMINPUTSPanel);
            app.DistanceD_bcEditFieldLabel.Text = 'Distance D_bc';
            app.DistanceD_bcEditField = uieditfield(app.SYSTEMINPUTSPanel, 'numeric');
            app.DistanceD_caEditFieldLabel = uilabel(app.SYSTEMINPUTSPanel);
            app.DistanceD_caEditFieldLabel.Text = 'Distance D_ca';
            app.DistanceD_caEditField = uieditfield(app.SYSTEMINPUTSPanel, 'numeric');
            app.MediumLineModelDropDownLabel = uilabel(app.SYSTEMINPUTSPanel);
            app.MediumLineModelDropDownLabel.Text = 'Medium Line Model';
            app.MediumLineModelDropDown = uidropdown(app.SYSTEMINPUTSPanel);
            app.MediumLineModelDropDown.Items = {'Nominal Pi', 'Nominal T'};
            app.MediumLineModelDropDown.Value = 'Nominal Pi';
            app.ReceivingEndVoltageVEditFieldLabel = uilabel(app.SYSTEMINPUTSPanel);
            app.ReceivingEndVoltageVEditFieldLabel.Text = 'Receiving End Voltage (V)';
            app.ReceivingEndVoltageVEditField = uieditfield(app.SYSTEMINPUTSPanel, 'numeric');
            app.ReceivingEndVoltageVEditField.Value = 66000;
            app.PerformanceCaseDropDownLabel = uilabel(app.SYSTEMINPUTSPanel);
            app.PerformanceCaseDropDownLabel.Text = 'Performance Case';
            app.PerformanceCaseDropDown = uidropdown(app.SYSTEMINPUTSPanel);
            app.PerformanceCaseDropDown.Items = {'CASE I', 'CASE II'};
            app.PerformanceCaseDropDown.Value = 'CASE I';
            app.PERFORMANCECURVESPanel = uipanel(app.UIFigure);
            app.PERFORMANCECURVESPanel.Title = 'PERFORMANCE CURVES';
            app.GridLayout = uigridlayout(app.PERFORMANCECURVESPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Efficiency vs Active Power')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Voltage Regulation vs Active Power')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.Visible = 'off';
            app.CALCULATEDPARAMETERSPanel = uipanel(app.UIFigure);
            app.CALCULATEDPARAMETERSPanel.Title = 'CALCULATED PARAMETERS';
            app.ResultsTable = uitable(app.CALCULATEDPARAMETERSPanel);
            app.ResultsTable.ColumnName = {'Parameter'; 'Symbol'; 'Value'; 'Unit'};
            app.ResultsTable.RowName = {};
            app.AEditFieldLabel = uilabel(app.CALCULATEDPARAMETERSPanel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.CALCULATEDPARAMETERSPanel, 'text');
            app.AEditField.Editable = 'off';
            app.BEditFieldLabel = uilabel(app.CALCULATEDPARAMETERSPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.CALCULATEDPARAMETERSPanel, 'text');
            app.BEditField.Editable = 'off';
            app.CEditFieldLabel = uilabel(app.CALCULATEDPARAMETERSPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.CALCULATEDPARAMETERSPanel, 'text');
            app.CEditField.Editable = 'off';
            app.DEditFieldLabel = uilabel(app.CALCULATEDPARAMETERSPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.CALCULATEDPARAMETERSPanel, 'text');
            app.DEditField.Editable = 'off';
            app.BEditField_2 = uieditfield(app.CALCULATEDPARAMETERSPanel, 'text');
            app.BEditField_2.Editable = 'off';
            app.DEditField_2 = uieditfield(app.CALCULATEDPARAMETERSPanel, 'text');
            app.DEditField_2.Editable = 'off';
            app.AEditField_2 = uieditfield(app.CALCULATEDPARAMETERSPanel, 'text');
            app.AEditField_2.Editable = 'off';
            app.CEditField_2 = uieditfield(app.CALCULATEDPARAMETERSPanel, 'text');
            app.CEditField_2.Editable = 'off';
            app.RUNFULLANALYSISButton = uibutton(app.UIFigure, 'push');
            app.RUNFULLANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @RUNFULLANALYSISButtonPushed, true);
            app.RUNFULLANALYSISButton.Text = 'RUN FULL ANALYSIS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Run_Here

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end